classdef OurWaveTankAppVideoAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        StartButton                   matlab.ui.control.Button
        ResetDataButton               matlab.ui.control.Button
        graphViewTab2                 matlab.ui.container.TabGroup
        amplitudeGraphTab2            matlab.ui.container.Tab
        amplitudeGraph2               matlab.ui.control.UIAxes
        singlePointViewTab2           matlab.ui.container.Tab
        singlePointGraph2             matlab.ui.control.UIAxes
        graphViewTab1                 matlab.ui.container.TabGroup
        amplitudeGraphTab1            matlab.ui.container.Tab
        amplitudeGraph1               matlab.ui.control.UIAxes
        singlePointViewTab            matlab.ui.container.Tab
        singlePointGraph1             matlab.ui.control.UIAxes
        AIvideo                       matlab.ui.control.UIAxes
        BIvideo                       matlab.ui.control.UIAxes
        video                         matlab.ui.control.UIAxes
        Tab2                          matlab.ui.container.Tab
        ViewButton                    matlab.ui.control.Button
        Crop4Slider                   matlab.ui.control.Slider
        Crop4Label                    matlab.ui.control.Label
        Crop3Slider                   matlab.ui.control.Slider
        Crop3Label                    matlab.ui.control.Label
        Crop2Slider                   matlab.ui.control.Slider
        Crop2Label                    matlab.ui.control.Label
        Crop1Slider                   matlab.ui.control.Slider
        Crop1Label                    matlab.ui.control.Label
        AIVerticalPosition            matlab.ui.control.Slider
        VerticalSlider_2Label         matlab.ui.control.Label
        AIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSlider_2Label       matlab.ui.control.Label
        BIVerticalPosition            matlab.ui.control.Slider
        HorizontalLabel               matlab.ui.control.Label
        BIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSliderLabel         matlab.ui.control.Label
        AfterImpactBoxPositionLabel   matlab.ui.control.Label
        BeforeImpactBoxPositionLabel  matlab.ui.control.Label
        AIsinglePoint                 matlab.ui.control.Slider
        AfterLabel                    matlab.ui.control.Label
        BIsinglePoint                 matlab.ui.control.Slider
        BeforeLabel                   matlab.ui.control.Label
        ViewBoxWidth                  matlab.ui.control.Slider
        WidthLabel                    matlab.ui.control.Label
        ViewBoxHeight                 matlab.ui.control.Slider
        HeightLabel                   matlab.ui.control.Label
        SinglePointLabel              matlab.ui.control.Label
        ViewBoxSizeLabel              matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        SettingsVideo                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function main(app)
            %Read the original video
            %originalVideo = vision.VideoFileReader("OurWave.mp4")
            %videoPlayer = vision.VideoPlayer;
            cam = webcam('HD Pro Webcam C920');
            
            global crop
            crop = [400, 400, 1920, 1080];
            global limit
            global increment
            limit = 1000;
            increment = 1000;
            
            % Initializing matricies and the time value for the graph
            global frameNum
            global BIamplitudeMatrix
            global BISinglePointMatrix
            global AIamplitudeMatrix
            global AISinglePointMatrix
            frameNum = 1;
            BIamplitudeMatrix = NaN(limit,1);
            BISinglePointMatrix = NaN(limit,1);
            AIamplitudeMatrix = NaN(limit,1);
            AISinglePointMatrix = NaN(limit,1);

%             configFile = fopen('config.txt', 'r');
%             configSettings = fscanf(configFile, '%f');
%             size(configSettings)
%             fclose(configFile);
%             ViewBoxHeightValue = configSettings(1); 
%             ViewBoxWidthValue = configSettings(2);
%             BIVerticalPositionValue = configSettings(3);
%             BIHorizontalPositionValue = configSettings(4);
%             AIVerticalPositionValue = configSettings(5);
%             AIHorizontalPositionValue = configSettings(6);
%             BISinglePointValue = configSettings(7);
%             AISinglePointValue = configSettings(8);
            ViewBoxHeightValue = app.ViewBoxHeight.Value;
            ViewBoxWidthValue = app.ViewBoxWidth.Value;
            BIVerticalPositionValue = app.BIVerticalPosition.Value;
            BIHorizontalPositionValue = app.BIHorizontalPosition.Value;
            AIVerticalPositionValue = app.AIVerticalPosition.Value;
            AIHorizontalPositionValue = app.AIHorizontalPosition.Value;
            BISinglePointValue = app.BIsinglePoint.Value;
            AISinglePointValue = app.AIsinglePoint.Value;
            crop(1) = app.Crop1Slider.Value;
            crop(2) = app.Crop2Slider.Value;
            crop(3) = app.Crop3Slider.Value;
            crop(4) = app.Crop4Slider.Value;


            while 2 > 1
                % Extends the preallocation size of the matrix if it passes the
                % limit
                if mod(frameNum, limit) == 0
                    limit = limit + increment
                    disp("b")
                    BIamplitudeMatrix = vertcat(BIamplitudeMatrix, NaN(increment, 1));
                    BISinglePointMatrix = vertcat(BISinglePointMatrix, NaN(increment, 1));
                    AIamplitudeMatrix = vertcat(AIamplitudeMatrix, NaN(increment, 1));
                    AISinglePointMatrix = vertcat(AISinglePointMatrix, NaN(increment, 1));
                end
                %videoPlayer(originalVideo) %moves it to the next frame I think
                croppedVid = imcrop(snapshot(cam), [crop(1),crop(2),crop(3),crop(4)]);
                %croppedVid = imcrop(originalVideo(), [crop(1),crop(2),crop(3),crop(4)]);
                % Video without boxes
                %imshow(croppedVid, 'Parent', app.video)
                % Video with boxes
                singlePointVid = insertShape(insertShape(croppedVid,'line', [AISinglePointValue 0 AISinglePointValue crop(4)], 'LineWidth', 2, 'Color', 'blue'),'line', [BISinglePointValue 0 BISinglePointValue crop(4)], 'LineWidth', 2, 'Color', 'blue');
                
                imshow(insertShape(insertShape(singlePointVid, 'filled-rectangle', [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue], 'LineWidth', 2, 'Color', 'Blue'), 'filled-rectangle', [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue], 'LineWidth', 2, 'Color', 'Red'), 'Parent', app.video)
                imshow(imcrop(croppedVid, [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]), 'Parent', app.BIvideo)
                imshow(imcrop(croppedVid, [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]), 'Parent', app.AIvideo)
                %% Analyzing the BI/Red Box Wave
                BWWave = edge(createMask2(imgaussfilt(croppedVid, 3)), 'canny');
                redBox = imcrop(BWWave, [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]);
                
                % Creates a NaN matrix the size of the red box
                BIrow = fillmissing(nan(1,size(redBox,2)), 'previous');
                % Loops through the each point along the wave to find
                % minima/maxima

                for k = 1:width(BIrow)
                    try 
                        % Since createMask() surrounds the whole extracted wave, this gets only the top wave 
                        %disp(redBox(:, k))
                        temp = find(redBox(:,k),1,'first');
                        if isempty(temp)
                        else
                            BIrow(k) = temp;
                        end
                    catch exception
                        throw(exception) 
                    end    
                end
                % Counterintuitive, however, min() finds maxima and max()
                % finds minima
                [BIlowY, ~] = min(BIrow);
                [BIhighY, ~] = max(BIrow);
                % Extremely scientific method of finding the calm level
                if frameNum == 1
                   BIcalmLvl = mean(rmmissing(BIrow));
                end
                BIamplitude = abs(BIhighY - BIlowY)/2;
                % Implement a setting for the config file for the '100'
                BIsinglePoint = BIrow(round(100)+1) - BIcalmLvl;

                BISinglePointMatrix(frameNum, 1) = BIsinglePoint;
                BIamplitudeMatrix(frameNum, 1) = BIamplitude;

                % Plotting
                plot(app.amplitudeGraph1, BIamplitudeMatrix, '-r')
                plot(app.amplitudeGraph2, BIamplitudeMatrix, '-r')
                plot(app.singlePointGraph1, BISinglePointMatrix, '-r')
                plot(app.singlePointGraph2, BISinglePointMatrix, '-r')
                hold(app.amplitudeGraph1, 'on')
                hold(app.amplitudeGraph2, 'on')
                hold(app.singlePointGraph1, 'on')
                hold(app.singlePointGraph2, 'on')
                %% Analyzing the AI/Blue Box Wave
                blueBox = imcrop(BWWave, [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]);
                
                % Creates a NaN matrix the size of the red box
                AIrow = fillmissing(nan(1,size(blueBox,2)), 'previous');

                % Loops through the each point along the wave to find
                % minima/maxima
                for k = 1:width(AIrow)
                    try 
                        temp = find(blueBox(:,k),1,'first');
                        if isempty(temp)
                        else
                            AIrow(k) = temp;
                        end
                    catch exception
                        throw(exception) 
                    end    
                end
                % Counterintuitive, however, min() finds maxima and max()
                % finds minima
                [AIlowY, ~] = min(AIrow);
                [AIhighY, ~] = max(AIrow);
                % Extremely scientific method of finding the calm level
                if frameNum == 1
                   AIcalmLvl = mean(rmmissing(AIrow));
                end
                AIamplitude = abs(AIhighY - AIlowY)/2;
                % Implement a setting for the config file for the '100'
                AIsinglePoint = AIrow(round(100)+1) - AIcalmLvl;

                BISinglePointMatrix(frameNum, 1) = BIsinglePoint;
                BIamplitudeMatrix(frameNum, 1) = BIamplitude;
                AISinglePointMatrix(frameNum, 1) = AIsinglePoint;
                AIamplitudeMatrix(frameNum, 1) = AIamplitude;
                plot(app.amplitudeGraph1, AIamplitudeMatrix, '-b')
                plot(app.amplitudeGraph2, AIamplitudeMatrix, '-b')
                plot(app.singlePointGraph1, AISinglePointMatrix, '-b')
                plot(app.singlePointGraph2, AISinglePointMatrix, '-b')
                hold(app.amplitudeGraph1, 'off')
                hold(app.amplitudeGraph2, 'off')
                hold(app.singlePointGraph1, 'off')
                hold(app.singlePointGraph2, 'off')

                frameNum = frameNum + 1
                %if isDone(originalVideo)
                %    originalVideo = vision.VideoFileReader("wave_03.mov")
                %end
            end
        end
        
        function resetSettingsView(app)
            imgPreview = imcrop(snapshot(webcam('HD Pro Webcam C920')), [app.Crop1Slider.Value, app.Crop2Slider.Value, app.Crop3Slider.Value, app.Crop4Slider.Value]);
            singlePointVid = insertShape(insertShape(imgPreview,'line', [app.AIsinglePoint.Value 0 app.AIsinglePoint.Value app.Crop4Slider.Value], 'LineWidth', 2, 'Color', 'blue'),'line', [app.BIsinglePoint.Value 0 app.BIsinglePoint.Value app.Crop4Slider.Value], 'LineWidth', 2, 'Color', 'blue');
            imshow(insertShape(insertShape(singlePointVid, 'filled-rectangle', [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Blue'), 'filled-rectangle', [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Red'), 'Parent', app.SettingsVideo)
            %imshow(snapshot(cam), 'Parent', app.BIvideo)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cam = webcam('HD Pro Webcam C920');
            resetSettingsView(app)
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            global limit
            global increment
            limit = 1000;
            increment = 1000;
            
            global frameNum
            global BIamplitudeMatrix
            global AIamplitudeMatrix
            global BISinglePointMatrix
            global AISinglePointMatrix

            frameNum = 1;
            BIamplitudeMatrix = NaN(limit,1);
            BISinglePointMatrix = NaN(limit,1);
            AIamplitudeMatrix = NaN(limit,1);
            AISinglePointMatrix = NaN(limit,1);
            disp("a")
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            main(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            configFile = fopen('config2.txt', 'wt');
            fprintf(configFile, '%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f', app.ViewBoxHeight.Value, app.ViewBoxWidth.Value, app.BIVerticalPosition.Value, app.BIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.AIHorizontalPosition.Value, app.BIsinglePoint.Value, app.AIsinglePoint.Value, app.Crop1Slider.Value, app.Crop4Slider.Value, app.Crop3Slider.Value, app.Crop4Slider.Value);
            fclose(configFile);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            configFile = fopen('config2.txt', 'r');
            configSettings = fscanf(configFile, '%f')
            size(configSettings)
            fclose(configFile);
            app.ViewBoxHeight.Value = configSettings(1);
            app.ViewBoxWidth.Value = configSettings(2);
            app.BIVerticalPosition.Value = configSettings(3);
            app.BIHorizontalPosition.Value = configSettings(4);
            app.AIVerticalPosition.Value = configSettings(5);
            app.AIHorizontalPosition.Value = configSettings(6);
            app.BIsinglePoint.Value = configSettings(7);
            app.AIsinglePoint.Value = configSettings(8);
            global crop
            crop(1) = configSettings(9);
            crop(2) = configSettings(10);
            crop(3) = configSettings(11);
            crop(4) = configSettings(12);
            resetSettingsView(app)
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            resetSettingsView(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.video = uiaxes(app.Tab);
            title(app.video, 'Title')
            xlabel(app.video, 'X')
            ylabel(app.video, 'Y')
            zlabel(app.video, 'Z')
            app.BIvideo = uiaxes(app.Tab);
            title(app.BIvideo, 'BI video (red box)')
            xlabel(app.BIvideo, 'X')
            ylabel(app.BIvideo, 'Y')
            zlabel(app.BIvideo, 'Z')
            app.AIvideo = uiaxes(app.Tab);
            title(app.AIvideo, 'AI video (blue box)')
            xlabel(app.AIvideo, 'X')
            ylabel(app.AIvideo, 'Y')
            zlabel(app.AIvideo, 'Z')
            app.graphViewTab1 = uitabgroup(app.Tab);
            app.amplitudeGraphTab1 = uitab(app.graphViewTab1);
            app.amplitudeGraphTab1.Title = 'Tab';
            app.amplitudeGraph1 = uiaxes(app.amplitudeGraphTab1);
            title(app.amplitudeGraph1, 'Title')
            xlabel(app.amplitudeGraph1, 'X')
            ylabel(app.amplitudeGraph1, 'Y')
            zlabel(app.amplitudeGraph1, 'Z')
            app.singlePointViewTab = uitab(app.graphViewTab1);
            app.singlePointViewTab.Title = 'Tab2';
            app.singlePointGraph1 = uiaxes(app.singlePointViewTab);
            title(app.singlePointGraph1, 'Title')
            xlabel(app.singlePointGraph1, 'X')
            ylabel(app.singlePointGraph1, 'Y')
            zlabel(app.singlePointGraph1, 'Z')
            app.graphViewTab2 = uitabgroup(app.Tab);
            app.amplitudeGraphTab2 = uitab(app.graphViewTab2);
            app.amplitudeGraphTab2.Title = 'Tab';
            app.amplitudeGraph2 = uiaxes(app.amplitudeGraphTab2);
            title(app.amplitudeGraph2, 'Title')
            xlabel(app.amplitudeGraph2, 'X')
            ylabel(app.amplitudeGraph2, 'Y')
            zlabel(app.amplitudeGraph2, 'Z')
            app.singlePointViewTab2 = uitab(app.graphViewTab2);
            app.singlePointViewTab2.Title = 'Tab2';
            app.singlePointGraph2 = uiaxes(app.singlePointViewTab2);
            title(app.singlePointGraph2, 'Title')
            xlabel(app.singlePointGraph2, 'X')
            ylabel(app.singlePointGraph2, 'Y')
            zlabel(app.singlePointGraph2, 'Z')
            app.ResetDataButton = uibutton(app.Tab, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Text = 'Reset Data';
            app.StartButton = uibutton(app.Tab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.SettingsVideo = uiaxes(app.Tab2);
            title(app.SettingsVideo, 'Preview')
            xlabel(app.SettingsVideo, 'X')
            ylabel(app.SettingsVideo, 'Y')
            zlabel(app.SettingsVideo, 'Z')
            app.LoadButton = uibutton(app.Tab2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.Tab2, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ViewBoxSizeLabel = uilabel(app.Tab2);
            app.ViewBoxSizeLabel.Text = 'View Box Size';
            app.SinglePointLabel = uilabel(app.Tab2);
            app.SinglePointLabel.Text = 'Single Point';
            app.HeightLabel = uilabel(app.Tab2);
            app.HeightLabel.Text = 'Height';
            app.ViewBoxHeight = uislider(app.Tab2);
            app.ViewBoxHeight.Limits = [0 1080];
            app.ViewBoxHeight.Value = 90;
            app.WidthLabel = uilabel(app.Tab2);
            app.WidthLabel.Text = 'Width';
            app.ViewBoxWidth = uislider(app.Tab2);
            app.ViewBoxWidth.Limits = [0 1920];
            app.ViewBoxWidth.Value = 280;
            app.BeforeLabel = uilabel(app.Tab2);
            app.BeforeLabel.Text = 'Before';
            app.BIsinglePoint = uislider(app.Tab2);
            app.AfterLabel = uilabel(app.Tab2);
            app.AfterLabel.Text = 'After';
            app.AIsinglePoint = uislider(app.Tab2);
            app.BeforeImpactBoxPositionLabel = uilabel(app.Tab2);
            app.BeforeImpactBoxPositionLabel.Text = 'Before Impact Box Position';
            app.AfterImpactBoxPositionLabel = uilabel(app.Tab2);
            app.AfterImpactBoxPositionLabel.Text = 'After Impact Box Position';
            app.HorizontalSliderLabel = uilabel(app.Tab2);
            app.HorizontalSliderLabel.Text = {'Horizontal'; ''};
            app.BIHorizontalPosition = uislider(app.Tab2);
            app.BIHorizontalPosition.Limits = [0 1920];
            app.BIHorizontalPosition.Value = 800;
            app.HorizontalLabel = uilabel(app.Tab2);
            app.HorizontalLabel.Text = 'Vertical';
            app.BIVerticalPosition = uislider(app.Tab2);
            app.BIVerticalPosition.Limits = [0 1080];
            app.BIVerticalPosition.Value = 80;
            app.HorizontalSlider_2Label = uilabel(app.Tab2);
            app.HorizontalSlider_2Label.Text = {'Horizontal'; ''};
            app.AIHorizontalPosition = uislider(app.Tab2);
            app.AIHorizontalPosition.Limits = [0 1920];
            app.AIHorizontalPosition.Value = 200;
            app.VerticalSlider_2Label = uilabel(app.Tab2);
            app.VerticalSlider_2Label.Text = 'Vertical';
            app.AIVerticalPosition = uislider(app.Tab2);
            app.AIVerticalPosition.Limits = [0 1080];
            app.AIVerticalPosition.Value = 80;
            app.Crop1Label = uilabel(app.Tab2);
            app.Crop1Label.Text = 'Crop 1';
            app.Crop1Slider = uislider(app.Tab2);
            app.Crop1Slider.Limits = [0 1920];
            app.Crop1Slider.Value = 400;
            app.Crop2Label = uilabel(app.Tab2);
            app.Crop2Label.Text = 'Crop 2';
            app.Crop2Slider = uislider(app.Tab2);
            app.Crop2Slider.Limits = [0 1080];
            app.Crop2Slider.Value = 400;
            app.Crop3Label = uilabel(app.Tab2);
            app.Crop3Label.Text = 'Crop 3';
            app.Crop3Slider = uislider(app.Tab2);
            app.Crop3Slider.Limits = [0 1920];
            app.Crop3Slider.Value = 1920;
            app.Crop4Label = uilabel(app.Tab2);
            app.Crop4Label.Text = 'Crop 4';
            app.Crop4Slider = uislider(app.Tab2);
            app.Crop4Slider.Limits = [0 1080];
            app.Crop4Slider.Value = 1080;
            app.ViewButton = uibutton(app.Tab2, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OurWaveTankAppVideoAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end