classdef WaveTankAppController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        WaveControlTab                matlab.ui.container.Tab
        WaveTypeButtonGroup           matlab.ui.container.ButtonGroup
        Button3                       matlab.ui.control.RadioButton
        Button2                       matlab.ui.control.RadioButton
        TypButton                     matlab.ui.control.RadioButton
        PowerSlider                   matlab.ui.control.Slider
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ControlWave                   matlab.ui.control.UIAxes
        WaveAnalysisTab               matlab.ui.container.Tab
        Button4                       matlab.ui.control.Button
        ResetDataButton               matlab.ui.control.Button
        graphViewTab1                 matlab.ui.container.TabGroup
        amplitudeGraphTab1            matlab.ui.container.Tab
        amplitudeGraph1               matlab.ui.control.UIAxes
        singlePointViewTab            matlab.ui.container.Tab
        singlePointGraph1             matlab.ui.control.UIAxes
        graphViewTab2                 matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        amplitudeGraph2               matlab.ui.control.UIAxes
        Tab2                          matlab.ui.container.Tab
        AnalysisAI                    matlab.ui.control.UIAxes
        AnalysisBI                    matlab.ui.control.UIAxes
        AnalysisFullWave              matlab.ui.control.UIAxes
        WaveCalibrationTab            matlab.ui.container.Tab
        AIsinglePoint                 matlab.ui.control.Slider
        AfterLabel                    matlab.ui.control.Label
        BIsinglePoint                 matlab.ui.control.Slider
        BeforeLabel                   matlab.ui.control.Label
        SinglePointLabel              matlab.ui.control.Label
        ExportSettingsButton          matlab.ui.control.Button
        LoadSettingsButton            matlab.ui.control.Button
        ResetSettingsButton           matlab.ui.control.Button
        ViewBoxWidth                  matlab.ui.control.Slider
        WidthLabel                    matlab.ui.control.Label
        ViewBoxHeight                 matlab.ui.control.Slider
        HeightLabel                   matlab.ui.control.Label
        ViewBoxSizeLabel              matlab.ui.control.Label
        LineViewSwitch                matlab.ui.control.Switch
        LineViewLabel                 matlab.ui.control.Label
        AIVerticalPosition            matlab.ui.control.Slider
        VerticalSlider_2Label         matlab.ui.control.Label
        AIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSlider_2Label       matlab.ui.control.Label
        AfterImpactBoxPositionLabel   matlab.ui.control.Label
        BIVerticalPosition            matlab.ui.control.Slider
        HorizontalLabel               matlab.ui.control.Label
        BIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSliderLabel         matlab.ui.control.Label
        BeforeImpactBoxPositionLabel  matlab.ui.control.Label
        AICalibration                 matlab.ui.control.UIAxes
        BICalibration                 matlab.ui.control.UIAxes
        FullCalibrationWave           matlab.ui.control.UIAxes
        Tab_2                         matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function [frameNum] = main(app)
            %cam = webcam('FaceTime HD Camera');
            cam = webcam('HD Pro Webcam C920');
            % Reading the video and starting the videoplayer
            % Arguments for imcrop() for just an image of the full wave
            global crop
            %crop = [500, 600, 1100, 275]
            crop = [0, 0, 2000, 2000]

            % Setting up sliders
            originalVideo = snapshot(cam);
            [cropHeight,cropWidth, dimensions] = size(imcrop(originalVideo, [crop(1),crop(2),crop(3),crop(4)]));
            app.ViewBoxWidth.Limits = [0 crop(3)];            
            app.BIHorizontalPosition.Limits = [0 cropWidth];    
            app.AIHorizontalPosition.Limits = [0 cropWidth];    
            app.ViewBoxHeight.Limits = [0 crop(4)];           
            app.BIVerticalPosition.Limits = [0 cropHeight];
            app.AIVerticalPosition.Limits = [0 cropHeight];
            app.BIsinglePoint.Limits = [1 cropWidth]; % this is currently the size of the larger area which shows the red and blue box outlines
            app.AIsinglePoint.Limits = [1 cropWidth]; 

            % Initializing matricies and the time value for the graph
            global frameNum
            global BIamplitudeMatrix
            global AIamplitudeMatrix
            global BISinglePointMatrix
            global AISinglePointMatrix
            frameNum = 0;
            BIamplitudeMatrix = [];
            AIamplitudeMatrix = [];
            BISinglePointMatrix = [];
            AISinglePointMatrix = [];

            while 0 < 1
                frameNum = frameNum + 1
                % Getting the first/next video frame
                originalVideo = snapshot(cam);
                croppedVid = imcrop(originalVideo, [crop(1),crop(2),crop(3),crop(4)]);

                % Creates the binary mask of the wave
                BWWave = edge(createMask2(imgaussfilt(croppedVid, 3)), 'canny');
                
                % Getting characteristics of full image of wave
                row = nan(1,size(BWWave,2));

                % Getting the characteristics of the full sized wave
                imshow(croppedVid)
                imshow(BWWave)
                for k = 1:crop(4) 
                    try 
                        %disp(BWWave(:,k))
                        row(k) = find(BWWave(:,k),1,'first');
                        [lowY, corXLow] = min(row);
                    catch exception
                        %disp(['Error: ' exception.message]);
                    end    
                end
                disp(row)
                if frameNum == 1
                   calmLvl = mean(rmmissing(row));
                   disp("calmlvl = " + calmLvl)
                end

                % Adds a white borderline of the wave crest to a colored img
                BWWave = 255 * repmat(uint8(BWWave), 1, 1, 3);

                fullEditedVideo = BWWave + croppedVid;
                % Inserts the yellow circle at the highest point
                fullEditedVideo = insertShape(fullEditedVideo, 'circle', [corXLow lowY 5], 'LineWidth', 10);
                % Inserts the red line at the calm level of the water
                fullEditedVideo = insertShape(fullEditedVideo,'line', [0 calmLvl crop(3)+2 calmLvl], 'LineWidth', 2, 'Color', 'red');
                % Inserts the two single point view lines
                fullEditedVideo = insertShape(fullEditedVideo,'line', [app.BIsinglePoint.Value 0 app.BIsinglePoint.Value crop(4)], 'LineWidth', 2, 'Color', 'blue');
                fullEditedVideo = insertShape(fullEditedVideo,'line', [app.AIsinglePoint.Value 0 app.AIsinglePoint.Value crop(4)], 'LineWidth', 2, 'Color', 'blue');
                % Draws the two boxes on the image preview
                fullEditedVideoWithBoxes = insertShape(fullEditedVideo, 'filled-rectangle', [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Red');
                fullEditedVideoWithBoxes = insertShape(fullEditedVideoWithBoxes, 'filled-rectangle', [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Blue');

                % Displays the full image
                imshow(fullEditedVideoWithBoxes, 'Parent', app.AnalysisFullWave)
                imshow(fullEditedVideoWithBoxes, 'Parent', app.ControlWave)
                if app.LineViewSwitch.Value == "Off"
                    imshow(fullEditedVideoWithBoxes, 'Parent', app.FullCalibrationWave)
                else
                    imshow(BWWave, 'Parent', app.FullCalibrationWave)
                end
                %% RED BOX
                % Analyzing and displaying the before impact box
                redBox = imcrop(fullEditedVideo, [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]);
                BIBWWave = edge(createMask(imgaussfilt(redBox, 3)), 'canny');
                % Analyzing the before impact box
                BIrow = nan(1,size(redBox,2));
                BIrow = fillmissing(BIrow, 'previous');
                
                for k = 1:crop(4) 
                    try 
                        BIrow(k) = find(BIBWWave(:,k),1,'first');
                        [BIlowY, ~] = min(BIrow);
                        [BIhighY, ~] = max(BIrow);
                        disp("BI high " + BIhighY)
                    catch exception
                        disp(['Error: ' exception.message]);
                    end    
                end
                if frameNum == 1
                   BIcalmLvl = mean(rmmissing(BIrow));
                end
                BIamplitude = abs(BIhighY - BIlowY)/2;
                disp(BIrow)
                BIsinglePoint = BWWave(round(app.BIsinglePoint.Value)+1) - BIcalmLvl;
                BISinglePointMatrix = [BISinglePointMatrix;BIsinglePoint]

                plot(app.singlePointGraph1, BISinglePointMatrix, '-r')
                %hold(app.singlePointGraph1, 'on')

                BIamplitudeMatrix = [BIamplitudeMatrix; BIamplitude];

                plot(app.amplitudeGraph1, BIamplitudeMatrix, '-r')
                plot(app.amplitudeGraph2, BIamplitudeMatrix, '-r')
                hold(app.amplitudeGraph1, 'on')
                hold(app.amplitudeGraph2, 'on')

                % Displaying the before impact box
                if app.LineViewSwitch.Value == "Off"
                    imshow(redBox, 'Parent', app.BICalibration)
                else
                    imshow(imcrop(BWWave, [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]), 'Parent', app.BICalibration)
                end
                
                imshow(redBox, 'Parent', app.AnalysisBI)
                %% BLUE BOX
                % Analyzing and displaying the after impact box
                blueBox = imcrop(fullEditedVideo, [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]);
                AIBWWave = edge(createMask(imgaussfilt(blueBox, 3)), 'canny');
                % Analyzing the before impact box
                AIrow = nan(1,size(blueBox,2));
                AIrow = fillmissing(AIrow, 'previous');
                
                for k = 1:crop(4) 
                    try 
                        AIrow(k) = find(AIBWWave(:,k),1,'first');
                        [AIlowY, AIcorXLow] = min(AIrow);
                        [AIhighY, AIcorXHigh] = max(AIrow); 
                    end    
                end
                AIhighLvl = AIlowY;
                if frameNum == 1
                   AIcalmLvl = mean(rmmissing(AIrow));
                end
                AIamplitude = abs(AIhighY - AIlowY)/2;
                AIamplitudeMatrix = [AIamplitudeMatrix; AIamplitude];
                plot(app.amplitudeGraph1, AIamplitudeMatrix, '-b')
                plot(app.amplitudeGraph2, AIamplitudeMatrix, '-b')
                hold(app.amplitudeGraph2, 'off')
                hold(app.amplitudeGraph1, 'off')
                
                % Displaying the image inside the after impact box (blue)
                blueBox = imcrop(fullEditedVideo, [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]);
                if app.LineViewSwitch.Value == "Off"
                    imshow(blueBox, 'Parent', app.AICalibration)
                else
                    imshow(imcrop(BWWave, [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]), 'Parent', app.AICalibration)
                end
                imshow(blueBox, 'Parent', app.AnalysisAI)

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            main(app)
        end

        % Button pushed function: ExportSettingsButton
        function ExportSettingsButtonPushed(app, event)
            configFile = fopen('config.txt', 'wt');
            fprintf(configFile, '%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f\n%5.1f', app.ViewBoxHeight.Value, app.ViewBoxWidth.Value, app.BIVerticalPosition.Value, app.BIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.AIHorizontalPosition.Value, app.BIsinglePoint.Value, app.AIsinglePoint.Value);
            fclose(configFile);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            configFile = fopen('config.txt', 'r');
            configSettings = fscanf(configFile, '%f')
            size(configSettings)
            fclose(configFile);
            app.ViewBoxHeight.Value = configSettings(1);
            app.ViewBoxWidth.Value = configSettings(2);
            app.BIVerticalPosition.Value = configSettings(3);
            app.BIHorizontalPosition.Value = configSettings(4);
            app.AIVerticalPosition.Value = configSettings(5);
            app.AIHorizontalPosition.Value = configSettings(6);
            app.BIsinglePoint.Value = configSettings(7);
            app.AIsinglePoint.Value = configSettings(8);
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            global frameNum
            global BIamplitudeMatrix
            global AIamplitudeMatrix
            global BISinglePointMatrix
            global AISinglePointMatrix
            frameNum = 0;
            BIamplitudeMatrix = [];
            AIamplitudeMatrix = [];
            BISinglePointMatrix = [];
            AISinglePointMatrix = [];
            disp("a")
        end

        % Button pushed function: ResetSettingsButton
        function ResetSettingsButtonPushed(app, event)
            global crop
            app.ViewBoxWidth.Limits = [0 crop(3)];            
            app.BIHorizontalPosition.Limits = [0 crop(3)];    
            app.AIHorizontalPosition.Limits = [0 crop(3)];    
            app.ViewBoxHeight.Limits = [0 crop(4)];           
            app.BIVerticalPosition.Limits = [0 crop(4)];
            app.AIVerticalPosition.Limits = [0 crop(4)];
            app.BIsinglePoint.Limits = [1 crop(3)]; % this is currently the size of the larger area which shows the red and blue box outlines
            app.AIsinglePoint.Limits = [1 crop(3)]; 
            
            app.ViewBoxHeight.Value = 90;
            app.ViewBoxWidth.Value = 280;
            app.BIVerticalPosition.Value = 80;
            app.BIHorizontalPosition.Value = 800;
            app.AIVerticalPosition.Value = 80;
            app.AIHorizontalPosition.Value = 200;
            app.BIsinglePoint.Value = 100;
            app.AIsinglePoint.Value = 100;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaveControlTab = uitab(app.TabGroup);
            app.WaveControlTab.Title = 'Wave Control';
            app.ControlWave = uiaxes(app.WaveControlTab);
            title(app.ControlWave, 'Wave')
            app.ControlWave.XTick = [];
            app.ControlWave.YTick = [];
            app.StartButton = uibutton(app.WaveControlTab, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.WaveControlTab, 'push');
            app.StopButton.Text = 'Stop';
            app.PowerSlider = uislider(app.WaveControlTab);
            app.WaveTypeButtonGroup = uibuttongroup(app.WaveControlTab);
            app.WaveTypeButtonGroup.Title = 'Wave Type';
            app.TypButton = uiradiobutton(app.WaveTypeButtonGroup);
            app.TypButton.Text = 'Typ';
            app.TypButton.Value = true;
            app.Button2 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button3.Text = 'Button3';
            app.WaveAnalysisTab = uitab(app.TabGroup);
            app.WaveAnalysisTab.Title = 'Wave Analysis';
            app.AnalysisFullWave = uiaxes(app.WaveAnalysisTab);
            title(app.AnalysisFullWave, 'Wave')
            app.AnalysisFullWave.XTick = [];
            app.AnalysisFullWave.YTick = [];
            app.AnalysisBI = uiaxes(app.WaveAnalysisTab);
            title(app.AnalysisBI, 'Before Impact')
            app.AnalysisBI.XTick = [];
            app.AnalysisBI.YTick = [];
            app.AnalysisAI = uiaxes(app.WaveAnalysisTab);
            title(app.AnalysisAI, 'After Impact')
            app.AnalysisAI.XTick = [];
            app.AnalysisAI.YTick = [];
            app.graphViewTab2 = uitabgroup(app.WaveAnalysisTab);
            app.Tab = uitab(app.graphViewTab2);
            app.Tab.Title = 'Tab';
            app.amplitudeGraph2 = uiaxes(app.Tab);
            title(app.amplitudeGraph2, 'Highest Amplitude Graph')
            xlabel(app.amplitudeGraph2, 'Time (frames)')
            ylabel(app.amplitudeGraph2, 'Height (pixels)')
            zlabel(app.amplitudeGraph2, 'Z')
            app.amplitudeGraph2.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.amplitudeGraph2.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.Tab2 = uitab(app.graphViewTab2);
            app.Tab2.Title = 'Tab2';
            app.graphViewTab1 = uitabgroup(app.WaveAnalysisTab);
            app.amplitudeGraphTab1 = uitab(app.graphViewTab1);
            app.amplitudeGraphTab1.Title = 'Tab';
            app.amplitudeGraph1 = uiaxes(app.amplitudeGraphTab1);
            title(app.amplitudeGraph1, 'Highest Amplitude Graph')
            xlabel(app.amplitudeGraph1, 'Time (frames)')
            ylabel(app.amplitudeGraph1, 'Height (pixels)')
            zlabel(app.amplitudeGraph1, 'Z')
            app.amplitudeGraph1.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.amplitudeGraph1.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'};
            app.amplitudeGraph1.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.amplitudeGraph1.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'};
            app.singlePointViewTab = uitab(app.graphViewTab1);
            app.singlePointViewTab.Title = 'Tab2';
            app.singlePointGraph1 = uiaxes(app.singlePointViewTab);
            title(app.singlePointGraph1, 'Height at single point view')
            xlabel(app.singlePointGraph1, 'Height (pixels)')
            ylabel(app.singlePointGraph1, 'time (frames)')
            zlabel(app.singlePointGraph1, 'Z')
            app.ResetDataButton = uibutton(app.WaveAnalysisTab, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Text = 'Reset Data';
            app.Button4 = uibutton(app.WaveAnalysisTab, 'push');
            app.Button4.Text = 'Button4';
            app.WaveCalibrationTab = uitab(app.TabGroup);
            app.WaveCalibrationTab.Title = 'Wave Calibration';
            app.FullCalibrationWave = uiaxes(app.WaveCalibrationTab);
            title(app.FullCalibrationWave, 'Full Wave')
            app.FullCalibrationWave.XTick = [];
            app.FullCalibrationWave.YTick = [];
            app.BICalibration = uiaxes(app.WaveCalibrationTab);
            title(app.BICalibration, {'Before Impact'; ''})
            app.BICalibration.XTick = [];
            app.BICalibration.YTick = [];
            app.AICalibration = uiaxes(app.WaveCalibrationTab);
            title(app.AICalibration, {'After Impact'; ''})
            app.AICalibration.XTick = [];
            app.AICalibration.YTick = [];
            app.BeforeImpactBoxPositionLabel = uilabel(app.WaveCalibrationTab);
            app.BeforeImpactBoxPositionLabel.Text = 'Before Impact Box Position';
            app.HorizontalSliderLabel = uilabel(app.WaveCalibrationTab);
            app.HorizontalSliderLabel.Text = {'Horizontal'; ''};
            app.BIHorizontalPosition = uislider(app.WaveCalibrationTab);
            app.BIHorizontalPosition.Limits = [0 1920];
            app.BIHorizontalPosition.Value = 800;
            app.HorizontalLabel = uilabel(app.WaveCalibrationTab);
            app.HorizontalLabel.Text = 'Vertical';
            app.BIVerticalPosition = uislider(app.WaveCalibrationTab);
            app.BIVerticalPosition.Limits = [0 1080];
            app.BIVerticalPosition.Value = 80;
            app.AfterImpactBoxPositionLabel = uilabel(app.WaveCalibrationTab);
            app.AfterImpactBoxPositionLabel.Text = 'After Impact Box Position';
            app.HorizontalSlider_2Label = uilabel(app.WaveCalibrationTab);
            app.HorizontalSlider_2Label.Text = {'Horizontal'; ''};
            app.AIHorizontalPosition = uislider(app.WaveCalibrationTab);
            app.AIHorizontalPosition.Limits = [0 1920];
            app.AIHorizontalPosition.Value = 200;
            app.VerticalSlider_2Label = uilabel(app.WaveCalibrationTab);
            app.VerticalSlider_2Label.Text = 'Vertical';
            app.AIVerticalPosition = uislider(app.WaveCalibrationTab);
            app.AIVerticalPosition.Limits = [0 1080];
            app.AIVerticalPosition.Value = 80;
            app.LineViewLabel = uilabel(app.WaveCalibrationTab);
            app.LineViewLabel.Text = 'Line View';
            app.LineViewSwitch = uiswitch(app.WaveCalibrationTab, 'slider');
            app.ViewBoxSizeLabel = uilabel(app.WaveCalibrationTab);
            app.ViewBoxSizeLabel.Text = 'View Box Size';
            app.HeightLabel = uilabel(app.WaveCalibrationTab);
            app.HeightLabel.Text = 'Height';
            app.ViewBoxHeight = uislider(app.WaveCalibrationTab);
            app.ViewBoxHeight.Limits = [0 1080];
            app.ViewBoxHeight.Value = 90;
            app.WidthLabel = uilabel(app.WaveCalibrationTab);
            app.WidthLabel.Text = 'Width';
            app.ViewBoxWidth = uislider(app.WaveCalibrationTab);
            app.ViewBoxWidth.Limits = [0 1920];
            app.ViewBoxWidth.Value = 280;
            app.ResetSettingsButton = uibutton(app.WaveCalibrationTab, 'push');
            app.ResetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSettingsButtonPushed, true);
            app.ResetSettingsButton.Text = 'Reset Settings';
            app.LoadSettingsButton = uibutton(app.WaveCalibrationTab, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.ExportSettingsButton = uibutton(app.WaveCalibrationTab, 'push');
            app.ExportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSettingsButtonPushed, true);
            app.ExportSettingsButton.Text = 'Export Settings';
            app.SinglePointLabel = uilabel(app.WaveCalibrationTab);
            app.SinglePointLabel.Text = 'Single Point';
            app.BeforeLabel = uilabel(app.WaveCalibrationTab);
            app.BeforeLabel.Text = 'Before';
            app.BIsinglePoint = uislider(app.WaveCalibrationTab);
            app.AfterLabel = uilabel(app.WaveCalibrationTab);
            app.AfterLabel.Text = 'After';
            app.AIsinglePoint = uislider(app.WaveCalibrationTab);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveTankAppController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end