classdef WaveTankAppVideoAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        ResetDataButton      matlab.ui.control.Button
        graphViewTab2        matlab.ui.container.TabGroup
        amplitudeGraphTab2   matlab.ui.container.Tab
        amplitudeGraph2      matlab.ui.control.UIAxes
        singlePointViewTab2  matlab.ui.container.Tab
        singlePointGraph2    matlab.ui.control.UIAxes
        graphViewTab1        matlab.ui.container.TabGroup
        amplitudeGraphTab1   matlab.ui.container.Tab
        amplitudeGraph1      matlab.ui.control.UIAxes
        singlePointViewTab   matlab.ui.container.Tab
        singlePointGraph1    matlab.ui.control.UIAxes
        AIvideo              matlab.ui.control.UIAxes
        BIvideo              matlab.ui.control.UIAxes
        video                matlab.ui.control.UIAxes
        Tab2                 matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Read the original video
            originalVideo = vision.VideoFileReader("wave_03.mov")
            videoPlayer = vision.VideoPlayer;
            crop = [500, 600, 1100, 275];
            global limit
            global increment
            limit = 1000;
            increment = 1000;
            
            % Initializing matricies and the time value for the graph
            global frameNum
            global BIamplitudeMatrix
            global BISinglePointMatrix
            global AIamplitudeMatrix
            global AISinglePointMatrix
            frameNum = 1;
            BIamplitudeMatrix = NaN(limit,1);
            BISinglePointMatrix = NaN(limit,1);
            AIamplitudeMatrix = NaN(limit,1);
            AISinglePointMatrix = NaN(limit,1);

            configFile = fopen('config.txt', 'r');
            configSettings = fscanf(configFile, '%f');
            size(configSettings)
            fclose(configFile);
            ViewBoxHeightValue = configSettings(1);
            ViewBoxWidthValue = configSettings(2);
            BIVerticalPositionValue = configSettings(3);
            BIHorizontalPositionValue = configSettings(4);
            AIVerticalPositionValue = configSettings(5);
            AIHorizontalPositionValue = configSettings(6);
            BISinglePointValue = configSettings(7);
            AISinglePointValue = configSettings(8);

            while 2 > 1
                % Extends the preallocation size of the matrix if it passes the
                % limit
                if mod(frameNum, limit) == 1
                    limit = limit + increment
                    disp("b")
                    BIamplitudeMatrix = vertcat(BIamplitudeMatrix, NaN(increment, 1))
                    BISinglePointMatrix = vertcat(BISinglePointMatrix, NaN(increment, 1))
                    AIamplitudeMatrix = vertcat(AIamplitudeMatrix, NaN(increment, 1))
                    AISinglePointMatrix = vertcat(AISinglePointMatrix, NaN(increment, 1))
                end
                videoPlayer(originalVideo) %moves it to the next frame I think
                croppedVid = imcrop(originalVideo(), [crop(1),crop(2),crop(3),crop(4)]);
                % Video without boxes
                %imshow(croppedVid, 'Parent', app.video)
                % Video with boxes
                singlePointVid = insertShape(insertShape(croppedVid,'line', [AIsinglePointValue 0 AIsinglePointValue crop(4)], 'LineWidth', 2, 'Color', 'blue'),'line', [BIsinglePointValue 0 BIsinglePointValue crop(4)], 'LineWidth', 2, 'Color', 'blue');
                
                imshow(insertShape(insertShape(singlePointVid, 'filled-rectangle', [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue], 'LineWidth', 2, 'Color', 'Blue'), 'filled-rectangle', [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue], 'LineWidth', 2, 'Color', 'Red'), 'Parent', app.video)
                imshow(imcrop(croppedVid, [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]), 'Parent', app.BIvideo)
                imshow(imcrop(croppedVid, [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]), 'Parent', app.AIvideo)
                %% Analyzing the BI/Red Box Wave
                BWWave = edge(createMask(imgaussfilt(croppedVid, 3)), 'canny');
                redBox = imcrop(BWWave, [BIHorizontalPositionValue, BIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]);
                
                % Creates a NaN matrix the size of the red box
                BIrow = fillmissing(nan(1,size(redBox,2)), 'previous');

                % Loops through the each point along the wave to find
                % minima/maxima
                for k = 1:crop(4)-2
                    try 
                        % Since createMask() surrounds the whole extracted wave, this gets only the top wave 
                        BIrow(k) = find(redBox(:,k),1,'first');
                        % Counterintuitive, however, min() finds maxima and
                        % max() finds minima
                        [BIlowY, ~] = min(BIrow);
                        [BIhighY, ~] = max(BIrow);
                    catch exception
                        throw(exception) 
                    end    
                end

                % Extremely scientific method of finding the calm level
                if frameNum == 1
                   BIcalmLvl = mean(rmmissing(BIrow));
                end
                BIamplitude = abs(BIhighY - BIlowY)/2;
                % Implement a setting for the config file for the '100'
                BIsinglePoint = BIrow(round(100)+1) - BIcalmLvl;

                BISinglePointMatrix(frameNum, 1) = BIsinglePoint;
                BIamplitudeMatrix(frameNum, 1) = BIamplitude;

                % Plotting
                plot(app.amplitudeGraph1, BIamplitudeMatrix, '-r')
                plot(app.amplitudeGraph2, BIamplitudeMatrix, '-r')
                plot(app.singlePointGraph1, BISinglePointMatrix, '-r')
                plot(app.singlePointGraph2, BISinglePointMatrix, '-r')
                hold(app.amplitudeGraph1, 'on')
                hold(app.amplitudeGraph2, 'on')
                hold(app.singlePointGraph1, 'on')
                hold(app.singlePointGraph2, 'on')
                %% Analyzing the AI/Blue Box Wave
                blueBox = imcrop(BWWave, [AIHorizontalPositionValue, AIVerticalPositionValue, ViewBoxWidthValue, ViewBoxHeightValue]);
                
                % Creates a NaN matrix the size of the red box
                AIrow = fillmissing(nan(1,size(blueBox,2)), 'previous');

                % Loops through the each point along the wave to find
                % minima/maxima
                for k = 1:crop(4)-2
                    try 
                        % Since createMask() surrounds the whole extracted wave, this gets only the top wave 
                        AIrow(k) = find(blueBox(:,k),1,'first');
                        % Counterintuitive, however, min() finds maxima and
                        % max() finds minima
                        [AIlowY, ~] = min(AIrow);
                        [AIhighY, ~] = max(AIrow);
                    catch exception
                        throw(exception) 
                    end    
                end

                % Extremely scientific method of finding the calm level
                if frameNum == 1
                   AIcalmLvl = mean(rmmissing(AIrow));
                end
                AIamplitude = abs(AIhighY - AIlowY)/2;
                % Implement a setting for the config file for the '100'
                AIsinglePoint = AIrow(round(100)+1) - AIcalmLvl;

                AISinglePointMatrix(frameNum, 1) = AIsinglePoint;
                AIamplitudeMatrix(frameNum, 1) = AIamplitude;
                plot(app.amplitudeGraph1, AIamplitudeMatrix, '-b')
                plot(app.amplitudeGraph2, AIamplitudeMatrix, '-b')
                plot(app.singlePointGraph1, AISinglePointMatrix, '-b')
                plot(app.singlePointGraph2, AISinglePointMatrix, '-b')
                hold(app.amplitudeGraph1, 'off')
                hold(app.amplitudeGraph2, 'off')
                hold(app.singlePointGraph1, 'off')
                hold(app.singlePointGraph2, 'off')

                frameNum = frameNum + 1
                if isDone(originalVideo)
                    originalVideo = vision.VideoFileReader("wave_03.mov")
                end
            end
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            global limit
            global increment
            limit = 1000;
            increment = 1000;
            
            global frameNum
            global BIamplitudeMatrix
            global AIamplitudeMatrix
            global BISinglePointMatrix
            global AISinglePointMatrix

            frameNum = 1;
            BIamplitudeMatrix = NaN(limit,1);
            BISinglePointMatrix = NaN(limit,1);
            AIamplitudeMatrix = NaN(limit,1);
            AISinglePointMatrix = NaN(limit,1);
            disp("a")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.video = uiaxes(app.Tab);
            title(app.video, 'Title')
            xlabel(app.video, 'X')
            ylabel(app.video, 'Y')
            zlabel(app.video, 'Z')
            app.BIvideo = uiaxes(app.Tab);
            title(app.BIvideo, 'BI video (red box)')
            xlabel(app.BIvideo, 'X')
            ylabel(app.BIvideo, 'Y')
            zlabel(app.BIvideo, 'Z')
            app.AIvideo = uiaxes(app.Tab);
            title(app.AIvideo, 'AI video (blue box)')
            xlabel(app.AIvideo, 'X')
            ylabel(app.AIvideo, 'Y')
            zlabel(app.AIvideo, 'Z')
            app.graphViewTab1 = uitabgroup(app.Tab);
            app.amplitudeGraphTab1 = uitab(app.graphViewTab1);
            app.amplitudeGraphTab1.Title = 'Tab';
            app.amplitudeGraph1 = uiaxes(app.amplitudeGraphTab1);
            title(app.amplitudeGraph1, 'Title')
            xlabel(app.amplitudeGraph1, 'X')
            ylabel(app.amplitudeGraph1, 'Y')
            zlabel(app.amplitudeGraph1, 'Z')
            app.singlePointViewTab = uitab(app.graphViewTab1);
            app.singlePointViewTab.Title = 'Tab2';
            app.singlePointGraph1 = uiaxes(app.singlePointViewTab);
            title(app.singlePointGraph1, 'Title')
            xlabel(app.singlePointGraph1, 'X')
            ylabel(app.singlePointGraph1, 'Y')
            zlabel(app.singlePointGraph1, 'Z')
            app.graphViewTab2 = uitabgroup(app.Tab);
            app.amplitudeGraphTab2 = uitab(app.graphViewTab2);
            app.amplitudeGraphTab2.Title = 'Tab';
            app.amplitudeGraph2 = uiaxes(app.amplitudeGraphTab2);
            title(app.amplitudeGraph2, 'Title')
            xlabel(app.amplitudeGraph2, 'X')
            ylabel(app.amplitudeGraph2, 'Y')
            zlabel(app.amplitudeGraph2, 'Z')
            app.singlePointViewTab2 = uitab(app.graphViewTab2);
            app.singlePointViewTab2.Title = 'Tab2';
            app.singlePointGraph2 = uiaxes(app.singlePointViewTab2);
            title(app.singlePointGraph2, 'Title')
            xlabel(app.singlePointGraph2, 'X')
            ylabel(app.singlePointGraph2, 'Y')
            zlabel(app.singlePointGraph2, 'Z')
            app.ResetDataButton = uibutton(app.Tab, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Text = 'Reset Data';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveTankAppVideoAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end