classdef WaveGeneratorApp_serial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        OnButton                matlab.ui.control.Button
        SpeedIncrease           matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        MonoPulseButton         matlab.ui.control.RadioButton
        ConstantPulseButton     matlab.ui.control.RadioButton
        FastSlowPulseButton     matlab.ui.control.RadioButton
        SpeedDecrease           matlab.ui.control.Button
        SpeedEditFieldLabel     matlab.ui.control.Label
        SpeedEditField          matlab.ui.control.EditField
        DistanceEditFieldLabel  matlab.ui.control.Label
        DistanceEditField       matlab.ui.control.EditField
        DistanceIncrease        matlab.ui.control.Button
        DistanceDecrease        matlab.ui.control.Button
        FastButton              matlab.ui.control.Button
        SlowButton              matlab.ui.control.Button
        MediumButton            matlab.ui.control.Button
        LowButton               matlab.ui.control.Button
        ModerateButton          matlab.ui.control.Button
        HighButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global arduino_board;
            global value;
            global wavetype;
            global speed;
            global distance;
            
            % Connect to arduino board
            delete(instrfind({'Port'},{'COM3'}));
            arduino_board = serial('COM3','BAUD', 9600);
            fopen(arduino_board);
            
            % Reset
            value = 0;
            wavetype = "MonoPulse";
            speed = 100; app.SpeedEditField.Value = string(speed);
            distance = 150; app.DistanceEditField.Value = string(distance);
            
            fprintf(arduino_board, value);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global wavetype;
            wavetype = app.ButtonGroup.SelectedObject.Text;
            disp(wavetype)
        end

        % Button pushed function: SpeedIncrease
        function SpeedIncreasePushed(app, event)
            global arduino_board;
            global speed
            speed = speed + 50;
            fprintf(arduino_board, 1);
            app.SpeedEditField.Value = string(speed);
        end

        % Button pushed function: SpeedDecrease
        function SpeedDecreasePushed(app, event)
            global arduino_board;
            global speed;
            speed = speed - 50;
            fprintf(arduino_board, 2);
            app.SpeedEditField.Value = string(speed);
        end

        % Button pushed function: DistanceIncrease
        function DistanceIncreaseButtonPushed(app, event)
            global arduino_board;
            global distance;
            distance = distance + 10;
            fprintf(arduino_board, 3);
            app.DistanceEditField.Value = string(distance);
        end

        % Button pushed function: DistanceDecrease
        function DistanceDecreaseButtonPushed(app, event)
            global arduino_board;
            global distance;
            distance = distance - 10;
            fprintf(arduino_board, 4);
            app.DistanceEditField.Value = string(distance);
        end

        % Button pushed function: SlowButton
        function SlowButtonPushed(app, event)
            global arduino_board;
            global speed;
            
            while speed ~= 1000
                if speed < 1000
                    speed = speed + 50;
                    fprintf(arduino_board, 1);
                elseif speed > 1000
                    speed = speed - 50;
                    fprintf(arduino_board, 2);
                end
            end
            app.SpeedEditField.Value = string(speed);
        end

        % Button pushed function: MediumButton
        function MediumButtonPushed(app, event)
            global arduino_board;
            global speed;
            
            while speed ~= 3000
                if speed < 3000
                    speed = speed + 50;
                    fprintf(arduino_board, 1);
                elseif speed > 3000
                    speed = speed - 50;
                    fprintf(arduino_board, 2);
                end
            end
            app.SpeedEditField.Value = string(speed);
        end

        % Button pushed function: FastButton
        function FastButtonPushed(app, event)
            global arduino_board;
            global speed;
            
            while speed ~= 5000
                if speed < 5000
                    speed = speed + 50;
                    fprintf(arduino_board, 1);
                elseif speed > 5000
                    speed = speed - 50;
                    fprintf(arduino_board, 2);
                end
            end
            app.SpeedEditField.Value = string(speed);
            
        end

        % Button pushed function: LowButton
        function LowButtonPushed(app, event)
            global arduino_board;
            global distance;
            
            while distance ~= 250
                if distance < 250
                    distance = distance + 10;
                    fprintf(arduino_board, 3);
                elseif distance > 250
                    distance = distance - 10;
                    fprintf(arduino_board, 4);
                end
            end
            
            app.DistanceEditField.Value = string(distance);
        end

        % Button pushed function: ModerateButton
        function ModerateButtonPushed(app, event)
            global arduino_board;
            global distance;
            
            while distance ~= 200
                if distance < 200
                    distance = distance + 10;
                    fprintf(arduino_board, 3);
                elseif distance > 200
                    distance = distance - 10;
                    fprintf(arduino_board, 4);
                end
            end
            
            app.DistanceEditField.Value = string(distance);
        end

        % Button pushed function: HighButton
        function HighButtonPushed(app, event)
            global arduino_board;
            global distance;
            
            while distance ~= 150
                if distance < 150
                    distance = distance + 10;
                    fprintf(arduino_board, 3);
                elseif distance > 150
                    distance = distance - 10;
                    fprintf(arduino_board, 4);
                end
            end
            
            app.DistanceEditField.Value = string(distance);
        end

        % Button pushed function: OnButton
        function OnButtonPushed(app, event)
            global arduino_board;
            global value;
            global wavetype;
            
            if wavetype == "MonoPulse"
                value = 255;
            elseif wavetype == "ConstantPulse"
                value = 254;
            elseif wavetype == "FastSlowPulse"
                value = 253;
            
            end
                
            % Write Wavetype, then 
            fprintf(arduino_board, value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global arduino_board;
            fprintf(arduino_board, 0);
            fclose(arduino_board);
            delete(instrfind({'Port'},{'COM3'}));
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OnButton = uibutton(app.UIFigure, 'push');
            app.OnButton.ButtonPushedFcn = createCallbackFcn(app, @OnButtonPushed, true);
            app.OnButton.Text = 'On';
            app.SpeedIncrease = uibutton(app.UIFigure, 'push');
            app.SpeedIncrease.ButtonPushedFcn = createCallbackFcn(app, @SpeedIncreasePushed, true);
            app.SpeedIncrease.Text = '+';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.MonoPulseButton = uiradiobutton(app.ButtonGroup);
            app.MonoPulseButton.Text = 'MonoPulse';
            app.MonoPulseButton.Value = true;
            app.ConstantPulseButton = uiradiobutton(app.ButtonGroup);
            app.ConstantPulseButton.Text = 'ConstantPulse';
            app.FastSlowPulseButton = uiradiobutton(app.ButtonGroup);
            app.FastSlowPulseButton.Text = 'FastSlowPulse';
            app.SpeedDecrease = uibutton(app.UIFigure, 'push');
            app.SpeedDecrease.ButtonPushedFcn = createCallbackFcn(app, @SpeedDecreasePushed, true);
            app.SpeedDecrease.Text = '-';
            app.SpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.UIFigure, 'text');
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'text');
            app.DistanceIncrease = uibutton(app.UIFigure, 'push');
            app.DistanceIncrease.ButtonPushedFcn = createCallbackFcn(app, @DistanceIncreaseButtonPushed, true);
            app.DistanceIncrease.Text = '+';
            app.DistanceDecrease = uibutton(app.UIFigure, 'push');
            app.DistanceDecrease.ButtonPushedFcn = createCallbackFcn(app, @DistanceDecreaseButtonPushed, true);
            app.DistanceDecrease.Text = '-';
            app.FastButton = uibutton(app.UIFigure, 'push');
            app.FastButton.ButtonPushedFcn = createCallbackFcn(app, @FastButtonPushed, true);
            app.FastButton.Text = 'Fast';
            app.SlowButton = uibutton(app.UIFigure, 'push');
            app.SlowButton.ButtonPushedFcn = createCallbackFcn(app, @SlowButtonPushed, true);
            app.SlowButton.Text = 'Slow';
            app.MediumButton = uibutton(app.UIFigure, 'push');
            app.MediumButton.ButtonPushedFcn = createCallbackFcn(app, @MediumButtonPushed, true);
            app.MediumButton.Text = 'Medium';
            app.LowButton = uibutton(app.UIFigure, 'push');
            app.LowButton.ButtonPushedFcn = createCallbackFcn(app, @LowButtonPushed, true);
            app.LowButton.Text = 'Low';
            app.ModerateButton = uibutton(app.UIFigure, 'push');
            app.ModerateButton.ButtonPushedFcn = createCallbackFcn(app, @ModerateButtonPushed, true);
            app.ModerateButton.Text = 'Moderate';
            app.HighButton = uibutton(app.UIFigure, 'push');
            app.HighButton.ButtonPushedFcn = createCallbackFcn(app, @HighButtonPushed, true);
            app.HighButton.Text = 'High';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveGeneratorApp_serial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end