classdef serial_com_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        OnButton             matlab.ui.control.Button
        OffButton            matlab.ui.control.Button
        ButtonGroup          matlab.ui.container.ButtonGroup
        MonoPulseButton      matlab.ui.control.RadioButton
        ConstantPulseButton  matlab.ui.control.RadioButton
        SpeedSliderLabel     matlab.ui.control.Label
        SpeedSlider          matlab.ui.control.Slider
        DistanceSliderLabel  matlab.ui.control.Label
        DistanceSlider       matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global arduino_board;
            global value;
            global wavetype;
            global speed;
            
            % global distance;
            
            % Connect to arduino board
            delete(instrfind({'Port'},{'COM5'}));
            arduino_board = serial('COM5','BAUD', 9600);
            fopen(arduino_board);
            
            % Stop motor at start
            value = 0;
            wavetype = "MonoPulse";
            speed = 0;
            % distance = 0;
            fprintf(arduino_board, value);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global wavetype;
            wavetype = app.ButtonGroup.SelectedObject.Text;
            disp(wavetype)
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            global speed;
            global arduino_board;
            speed = round(app.SpeedSlider.Value);
            fprintf(arduino_board, 1);
            pause(0.1)
            fprintf(arduino_board, speed);
                
            disp(speed);
        end

        % Button pushed function: OnButton
        function OnButtonPushed(app, event)
            global arduino_board;
            global value;
            global wavetype;
            global speed;
            
            if wavetype == "MonoPulse"
                value = 3;
            elseif wavetype == "ConstantPulse"
                value = 4;
            
            end
                
            % Write Wavetype, then 
            fprintf(arduino_board, 1);
            %fprintf(arduino_board, 255);
            %fprintf(arduino_board, 100);
            %fprintf(arduino_board, distance);
        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            global arduino_board;
            fprintf(arduino_board, 2);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global arduino_board;
            fprintf(arduino_board, 0);
            fclose(arduino_board);
            delete(instrfind({'Port'},{'COM3'}));
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OnButton = uibutton(app.UIFigure, 'push');
            app.OnButton.ButtonPushedFcn = createCallbackFcn(app, @OnButtonPushed, true);
            app.OnButton.Text = 'On';
            app.OffButton = uibutton(app.UIFigure, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Text = 'Off';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.MonoPulseButton = uiradiobutton(app.ButtonGroup);
            app.MonoPulseButton.Text = 'MonoPulse';
            app.MonoPulseButton.Value = true;
            app.ConstantPulseButton = uiradiobutton(app.ButtonGroup);
            app.ConstantPulseButton.Text = 'ConstantPulse';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [100 255];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Value = 100;
            app.DistanceSliderLabel = uilabel(app.UIFigure);
            app.DistanceSliderLabel.Text = 'Distance';
            app.DistanceSlider = uislider(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = serial_com_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end