classdef WaveTankApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        WaveControlTab                matlab.ui.container.Tab
        WaveTypeButtonGroup           matlab.ui.container.ButtonGroup
        Button3                       matlab.ui.control.RadioButton
        Button2                       matlab.ui.control.RadioButton
        TypButton                     matlab.ui.control.RadioButton
        PowerSlider                   matlab.ui.control.Slider
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ControlWave                   matlab.ui.control.UIAxes
        WaveAnalysisTab               matlab.ui.container.Tab
        TabGroup3                     matlab.ui.container.TabGroup
        Tab_2                         matlab.ui.container.Tab
        amplitudeGraph1               matlab.ui.control.UIAxes
        Tab2_2                        matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        Tab2                          matlab.ui.container.Tab
        AnalysisAI                    matlab.ui.control.UIAxes
        AnalysisBI                    matlab.ui.control.UIAxes
        AnalysisFullWave              matlab.ui.control.UIAxes
        WaveCalibrationTab            matlab.ui.container.Tab
        ExportSettingsButton          matlab.ui.control.Button
        LoadSettingsButton            matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ViewBoxWidth                  matlab.ui.control.Slider
        HorizontalSlider_3Label       matlab.ui.control.Label
        ViewBoxHeight                 matlab.ui.control.Slider
        HeightLabel                   matlab.ui.control.Label
        ViewBoxSizeLabel              matlab.ui.control.Label
        LineViewSwitch                matlab.ui.control.Switch
        LineViewLabel                 matlab.ui.control.Label
        AIVerticalPosition            matlab.ui.control.Slider
        VerticalSlider_2Label         matlab.ui.control.Label
        AIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSlider_2Label       matlab.ui.control.Label
        AfterImpactBoxLabel           matlab.ui.control.Label
        BIVerticalPosition            matlab.ui.control.Slider
        HorizontalLabel               matlab.ui.control.Label
        BIHorizontalPosition          matlab.ui.control.Slider
        HorizontalSliderLabel         matlab.ui.control.Label
        BeforeImpactBoxPositionLabel  matlab.ui.control.Label
        AICalibration                 matlab.ui.control.UIAxes
        BICalibration                 matlab.ui.control.UIAxes
        FullCalibrationWave           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Reading the video and starting the videoplayer
            originalVideo = vision.VideoFileReader("wave_03.mov")
            videoPlayer = vision.VideoPlayer;
            % Arguments for imcrop() for just an image of the full wave,
            % but none of the text and such
            crop1 = 500;
            crop2 = 600; 
            crop3 = 1100;
            crop4 = 275;
            % Setting up sliders
            % Keeping this code below because it will be useful later on
            %[heightFullIMG,widthFullIMG, dimensions] = size(originalVideo());
            %app.ViewBoxWidth.Limits = [0 widthFullIMG];
            %app.BIHorizontalPosition.Limits = [0 widthFullIMG];
            %app.AIHorizontalPosition.Limits = [0 widthFullIMG];
            %app.ViewBoxHeight.Limits = [0 heightFullIMG];
            %app.BIVerticalPosition.Limits = [0 heightFullIMG];
            %app.AIVerticalPosition.Limits = [0 heightFullIMG];
            
            [cropHeight,cropWidth, dimensions] = size(imcrop(originalVideo(), [crop1,crop2,crop3,crop4]));
            app.ViewBoxWidth.Limits = [0 crop3];            
            app.BIHorizontalPosition.Limits = [0 cropWidth];    
            app.AIHorizontalPosition.Limits = [0 cropWidth];    
            app.ViewBoxHeight.Limits = [0 crop4];           
            app.BIVerticalPosition.Limits = [0 cropHeight];
            app.AIVerticalPosition.Limits = [0 cropHeight];
            
            frameNum = 0;
            BIamplitudeMatrix = []
            AIamplitudeMatrix = []
            while 0 < 1
                frameNum = frameNum + 1

                % Getting the first/next video frame
                videoPlayer(originalVideo)                

                % Creates the binary mask of the wave
                croppedVid = imcrop(originalVideo(), [crop1,crop2,crop3,crop4]);
                BWWave = edge(createMask(imgaussfilt(croppedVid, 3)), 'canny');
                
                % Getting characteristics of full image of wave

                row = nan(1,size(BWWave,2));
                row = fillmissing(row, 'previous');
                
                % Getting the characteristics of the full sized wave
                
                [heightIMG,widthIMG] = size(BWWave);
                for k = 1:crop3+1; 
                    try 
                        row(k) = find(BWWave(:,k),1,'first');
                        [lowY, corXLow] = min(row);
                    end    
                end
                if frameNum == 1
                   calmLvl = mean(rmmissing(row));
                end

                % Adds a white borderline of the wave crest to a colored img
                fullEditedVideo = BWWave + croppedVid;
                % Inserts the yellow circle at the highest point
                fullEditedVideo = insertShape(fullEditedVideo, 'circle', [corXLow lowY 5], 'LineWidth', 10);
                % Inserts the red line at the calm level of the water
                fullEditedVideo = insertShape(fullEditedVideo,'line', [0 calmLvl widthIMG+1 calmLvl], 'LineWidth', 2, 'Color', 'red');
                
                % Draws the two boxes on the image preview
                fullEditedVideoWithBoxes = insertShape(fullEditedVideo, 'filled-rectangle', [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Red');
                fullEditedVideoWithBoxes = insertShape(fullEditedVideoWithBoxes, 'filled-rectangle', [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value], 'LineWidth', 2, 'Color', 'Blue');

                % Displays the full image
                imshow(fullEditedVideoWithBoxes, 'Parent', app.AnalysisFullWave)
                imshow(fullEditedVideoWithBoxes, 'Parent', app.ControlWave)
                if app.LineViewSwitch.Value == "Off"
                    imshow(fullEditedVideoWithBoxes, 'Parent', app.FullCalibrationWave)
                else
                    imshow(BWWave, 'Parent', app.FullCalibrationWave)
                end

                % Analyzing and displaying the before impact box
                redBox = imcrop(fullEditedVideo, [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]);
                BIBWWave = edge(createMask(imgaussfilt(redBox, 3)), 'canny');
                % Analyzing the before impact box
                BIrow = nan(1,size(redBox,2));
                BIrow = fillmissing(BIrow, 'previous');
                
                for k = 1:crop3+1; 
                    try 
                        BIrow(k) = find(BIBWWave(:,k),1,'first');
                        [BIlowY, BIcorXLow] = min(BIrow);
                        [BIhighY, BIcorXHigh] = max(BIrow); 
                    end    
                end
                BIhighLvl = BIlowY;
                if frameNum == 1
                   BIcalmLvl = mean(rmmissing(BIrow));
                end
                BIamplitude = BIcalmLvl - BIlowY;
                BIamplitudeMatrix = [BIamplitudeMatrix; BIamplitude]
                plot(app.amplitudeGraph1, BIamplitudeMatrix, '-b')
                hold(app.amplitudeGraph1, 'on')
                % Displaying the before impact box
                if app.LineViewSwitch.Value == "Off"
                    imshow(redBox, 'Parent', app.BICalibration)
                else
                    imshow(imcrop(BWWave, [app.BIHorizontalPosition.Value, app.BIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]), 'Parent', app.BICalibration)
                end
                
                imshow(redBox, 'Parent', app.AnalysisBI)
                % Analyzing and displaying the after impact box
                blueBox = imcrop(fullEditedVideo, [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]);
                AIBWWave = edge(createMask(imgaussfilt(blueBox, 3)), 'canny');
                % Analyzing the before impact box
                AIrow = nan(1,size(blueBox,2));
                AIrow = fillmissing(AIrow, 'previous');
                
                for k = 1:crop3+1; 
                    try 
                        AIrow(k) = find(AIBWWave(:,k),1,'first');
                        [AIlowY, AIcorXLow] = min(AIrow);
                        [AIhighY, AIcorXHigh] = max(AIrow); 
                    end    
                end
                AIhighLvl = AIlowY;
                if frameNum == 1
                   AIcalmLvl = mean(rmmissing(AIrow));
                end
                AIamplitude = AIcalmLvl - AIlowY;
                AIamplitudeMatrix = [AIamplitudeMatrix; AIamplitude]
                plot(app.amplitudeGraph1, AIamplitudeMatrix, '-r')
                hold(app.amplitudeGraph1, 'off')
                %plot(app.amplitudeGraph1, frameNum, AIamplitudeMatrix, frameNum, BIamplitudeMatrix)
                
                % Displaying the image inside the after impact box (blue)
                blueBox = imcrop(fullEditedVideo, [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]);
                if app.LineViewSwitch.Value == "Off"
                    imshow(blueBox, 'Parent', app.AICalibration)
                else
                    imshow(imcrop(BWWave, [app.AIHorizontalPosition.Value, app.AIVerticalPosition.Value, app.ViewBoxWidth.Value, app.ViewBoxHeight.Value]), 'Parent', app.AICalibration)
                end
                imshow(blueBox, 'Parent', app.AnalysisAI)

                % Loops the video when it finishes
                if isDone(originalVideo)
                    originalVideo = vision.VideoFileReader("wave_03.mov")
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaveControlTab = uitab(app.TabGroup);
            app.WaveControlTab.Title = 'Wave Control';
            app.ControlWave = uiaxes(app.WaveControlTab);
            title(app.ControlWave, 'Wave')
            app.ControlWave.XTick = [];
            app.ControlWave.YTick = [];
            app.StartButton = uibutton(app.WaveControlTab, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.WaveControlTab, 'push');
            app.StopButton.Text = 'Stop';
            app.PowerSlider = uislider(app.WaveControlTab);
            app.WaveTypeButtonGroup = uibuttongroup(app.WaveControlTab);
            app.WaveTypeButtonGroup.Title = 'Wave Type';
            app.TypButton = uiradiobutton(app.WaveTypeButtonGroup);
            app.TypButton.Text = 'Typ';
            app.TypButton.Value = true;
            app.Button2 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button3.Text = 'Button3';
            app.WaveAnalysisTab = uitab(app.TabGroup);
            app.WaveAnalysisTab.Title = 'Wave Analysis';
            app.AnalysisFullWave = uiaxes(app.WaveAnalysisTab);
            title(app.AnalysisFullWave, 'Wave')
            app.AnalysisFullWave.XTick = [];
            app.AnalysisFullWave.YTick = [];
            app.AnalysisBI = uiaxes(app.WaveAnalysisTab);
            title(app.AnalysisBI, 'Before Impact')
            app.AnalysisBI.XTick = [];
            app.AnalysisBI.YTick = [];
            app.AnalysisAI = uiaxes(app.WaveAnalysisTab);
            title(app.AnalysisAI, 'After Impact')
            app.AnalysisAI.XTick = [];
            app.AnalysisAI.YTick = [];
            app.TabGroup2 = uitabgroup(app.WaveAnalysisTab);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.TabGroup3 = uitabgroup(app.WaveAnalysisTab);
            app.Tab_2 = uitab(app.TabGroup3);
            app.Tab_2.Title = 'Tab';
            app.amplitudeGraph1 = uiaxes(app.Tab_2);
            title(app.amplitudeGraph1, 'Amplitude Graph')
            xlabel(app.amplitudeGraph1, 'Time (frames)')
            ylabel(app.amplitudeGraph1, 'Amplitude (pixels)')
            zlabel(app.amplitudeGraph1, 'Z')
            app.Tab2_2 = uitab(app.TabGroup3);
            app.Tab2_2.Title = 'Tab2';
            app.WaveCalibrationTab = uitab(app.TabGroup);
            app.WaveCalibrationTab.Title = 'Wave Calibration';
            app.FullCalibrationWave = uiaxes(app.WaveCalibrationTab);
            title(app.FullCalibrationWave, 'Full Wave')
            app.FullCalibrationWave.XTick = [];
            app.FullCalibrationWave.YTick = [];
            app.BICalibration = uiaxes(app.WaveCalibrationTab);
            title(app.BICalibration, {'Before Impact'; ''})
            app.BICalibration.XTick = [];
            app.BICalibration.YTick = [];
            app.AICalibration = uiaxes(app.WaveCalibrationTab);
            title(app.AICalibration, {'After Impact'; ''})
            app.AICalibration.XTick = [];
            app.AICalibration.YTick = [];
            app.BeforeImpactBoxPositionLabel = uilabel(app.WaveCalibrationTab);
            app.BeforeImpactBoxPositionLabel.Text = 'Before Impact Box Position';
            app.HorizontalSliderLabel = uilabel(app.WaveCalibrationTab);
            app.HorizontalSliderLabel.Text = {'Horizontal'; ''};
            app.BIHorizontalPosition = uislider(app.WaveCalibrationTab);
            app.BIHorizontalPosition.Limits = [0 1920];
            app.BIHorizontalPosition.Value = 800;
            app.HorizontalLabel = uilabel(app.WaveCalibrationTab);
            app.HorizontalLabel.Text = 'Vertical';
            app.BIVerticalPosition = uislider(app.WaveCalibrationTab);
            app.BIVerticalPosition.Limits = [0 1080];
            app.BIVerticalPosition.Value = 80;
            app.AfterImpactBoxLabel = uilabel(app.WaveCalibrationTab);
            app.AfterImpactBoxLabel.Text = 'After Impact Box';
            app.HorizontalSlider_2Label = uilabel(app.WaveCalibrationTab);
            app.HorizontalSlider_2Label.Text = {'Horizontal'; ''};
            app.AIHorizontalPosition = uislider(app.WaveCalibrationTab);
            app.AIHorizontalPosition.Limits = [0 1920];
            app.AIHorizontalPosition.Value = 200;
            app.VerticalSlider_2Label = uilabel(app.WaveCalibrationTab);
            app.VerticalSlider_2Label.Text = 'Vertical';
            app.AIVerticalPosition = uislider(app.WaveCalibrationTab);
            app.AIVerticalPosition.Limits = [0 1080];
            app.AIVerticalPosition.Value = 80;
            app.LineViewLabel = uilabel(app.WaveCalibrationTab);
            app.LineViewLabel.Text = 'Line View';
            app.LineViewSwitch = uiswitch(app.WaveCalibrationTab, 'slider');
            app.ViewBoxSizeLabel = uilabel(app.WaveCalibrationTab);
            app.ViewBoxSizeLabel.Text = 'View Box Size';
            app.HeightLabel = uilabel(app.WaveCalibrationTab);
            app.HeightLabel.Text = 'Height';
            app.ViewBoxHeight = uislider(app.WaveCalibrationTab);
            app.ViewBoxHeight.Limits = [0 1080];
            app.ViewBoxHeight.Value = 90;
            app.HorizontalSlider_3Label = uilabel(app.WaveCalibrationTab);
            app.HorizontalSlider_3Label.Text = {'Horizontal'; ''};
            app.ViewBoxWidth = uislider(app.WaveCalibrationTab);
            app.ViewBoxWidth.Limits = [0 1920];
            app.ViewBoxWidth.Value = 280;
            app.ResetButton = uibutton(app.WaveCalibrationTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.LoadSettingsButton = uibutton(app.WaveCalibrationTab, 'push');
            app.LoadSettingsButton.Text = 'Load Settings';
            app.ExportSettingsButton = uibutton(app.WaveCalibrationTab, 'push');
            app.ExportSettingsButton.Text = 'Export Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveTankApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end