classdef WebcamTrial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        PlotButton      matlab.ui.control.Button
        UIAxesEdited    matlab.ui.control.UIAxes
        UIAxesUnedited  matlab.ui.control.UIAxes
        Tab2            matlab.ui.container.Tab
        UIAxesWebcam    matlab.ui.control.UIAxes
        Label           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Reading the video and starting the videoplayer and camera
            originalVideo = vision.VideoFileReader("wave_03.mov")
            videoPlayer = vision.VideoPlayer;
            cam = webcam('FaceTime HD Camera');
            i = 0; 
            frameNum = 0;
            while 0 < 1
                % Showing the original, unedited video
                frameNum = frameNum + 1;
                videoPlayer(originalVideo)
                imshow(originalVideo(), 'Parent', app.UIAxesUnedited)
                
                %% Showing and editing the edited video
                    %Creates the mask of the wave
                BWWave = edge(createMask(imgaussfilt(imcrop(originalVideo(), [500,600,1100,275]),3)), 'canny');
                    %No idea what this is for and what it does but it works
                row = nan(1,size(BWWave,2));
                [heightIMG,widthIMG] = size(BWWave);
                line = zeros(heightIMG, widthIMG);

                js = zeros(1, widthIMG-2);
                ls = zeros(1, widthIMG-2);
                for j = 1:widthIMG; 
                    for l = 1:heightIMG %figure out how to get height
                        if BWWave(l,j) == 1
                            line(l, j) = 1;
                            js(j-1) = j;
                            ls(j-1) = l;
                            break
                        end
                    end
                end
                row = fillmissing(row, 'previous');
                % Finding height of calm water
                for k = 1:widthIMG; 
                    try 
                        row(k) = find(BWWave(:,k),1,'first');
                        [lowY, corX] = min(row);
                        [highY, corXHigh] = max(row)
                    end    
                end
             
                calmLvl = lowY;
                %Ended at line 80
                editedVideo = BWWave + imcrop(originalVideo(), [500,600,1100,275]);
                editedVideo = insertShape(editedVideo, 'circle', [corX lowY 5], 'LineWidth', 10);
                editedVideo = insertShape(editedVideo,'line', [0 calmLvl widthIMG+1 calmLvl], 'LineWidth', 2, 'Color', 'red');
                imshow(editedVideo, 'Parent', app.UIAxesEdited)
                
                % Showing the camera video
                imshow(snapshot(cam), 'Parent', app.UIAxesWebcam)

                if isDone(originalVideo) % Restarts the videoplayer when it finishes
                    originalVideo = vision.VideoFileReader("wave_03.mov")
                    i = i + 1
                    frameNum = 0
                    if i == 3 % Stops the program because the exit button doesn't really work well
                        close(app.UIFigure)
                    end
                    
                end
            end
        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxesUnedited = uiaxes(app.Tab);
            app.UIAxesUnedited.XTick = [];
            app.UIAxesUnedited.YTick = [];
            app.UIAxesEdited = uiaxes(app.Tab);
            app.UIAxesEdited.XTick = [];
            app.UIAxesEdited.YTick = [];
            app.PlotButton = uibutton(app.Tab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxesWebcam = uiaxes(app.Tab2);
            app.UIAxesWebcam.XTick = [];
            app.UIAxesWebcam.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WebcamTrial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end